classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        Label_26           matlab.ui.control.Label
        Label_25           matlab.ui.control.Label
        Label_24           matlab.ui.control.Label
        Label_23           matlab.ui.control.Label
        Label_22           matlab.ui.control.Label
        Label_21           matlab.ui.control.Label
        Label_20           matlab.ui.control.Label
        Label_17           matlab.ui.control.Label
        startButton        matlab.ui.control.StateButton
        Label_10           matlab.ui.control.Label
        Label_9            matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        costFighter        matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        c_3Label           matlab.ui.control.Label
        c_1Label           matlab.ui.control.Label
        costFire           matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        t_1Label           matlab.ui.control.Label
        timeBegin          matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        betaLabel          matlab.ui.control.Label
        speedFire          matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        c_2Label           matlab.ui.control.Label
        costTime           matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        lambdaLabel        matlab.ui.control.Label
        speedFighter       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        xLabel             matlab.ui.control.Label
        fighter            matlab.ui.control.Spinner
        Label              matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        Label_28           matlab.ui.control.Label
        Label_27           matlab.ui.control.Label
        outputCostForest   matlab.ui.control.Label
        outputCostFighter  matlab.ui.control.Label
        outputCostTotal    matlab.ui.control.Label
        outputArea         matlab.ui.control.Label
        outputTime         matlab.ui.control.Label
        Label_15           matlab.ui.control.Label
        Label_14           matlab.ui.control.Label
        Label_13           matlab.ui.control.Label
        Label_12           matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        AxesBurned         matlab.ui.control.UIAxes
        AxesTotalCost      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            % read input values
            value = app.startButton.Value;
            X = app.fighter.Value;
            lambda = app.speedFighter.Value;
            beta = app.speedFire.Value;
            t_1 = app.timeBegin.Value;

            c_1 = app.costFire.Value;
            c_2 = app.costTime.Value;
            c_3 = app.costFighter.Value;

            % calculate burned area until t_1
            func_1 = @(t) beta * t;
            B_1 = integral(func_1,0,t_1);

            app.outputArea.Text = num2str(B_1,'%.2f') + "亩";

            % calculate t_2, time required to put out fire 
            func_2 = @(t) integral(@(t)(beta - lambda * X) * t,t_1,t) + B_1;
            options = optimoptions('fsolve', 'TolFun', 1e-6, 'TolX', 1e-6);
            t_2=fsolve(func_2,t_1,options);

            app.outputTime.Text = num2str(t_2,'%.2f') + "小时";
            
            % calculate cost
            cost_1 = c_1 * B_1;
            cost_2 = X * ((t_2 - t_1)* c_2 + c_3);
            costTotal = cost_1 + cost_2;

            app.outputCostForest.Text = num2str(cost_1,'%.2f') + "元";
            app.outputCostFighter.Text = num2str(cost_2,'%.2f') + "元";
            app.outputCostTotal.Text = num2str(costTotal,'%.2f') + "元";

            % plot axes
            count = 0;
            clc;
            clear X_1 Y_1;
            axis(app.AxesBurned,[0,t_2+0.5,0,B_1+5]);

            % before t_1
            for i = 0:0.02:t_1
                count = count + 1;
                X_1(count) = count * 0.02;
                Y_1(count) = integral(@(t) 30 * t,0,i);
                
                plot(app.AxesBurned,X_1,Y_1,'r');
                area(app.AxesBurned,X_1,Y_1,'FaceColor','b');
                hold(app.AxesBurned,"on");
                             
                pause(1/10);
            end
            
            clear X_2 Y_2;
            % after t_1
            count_2 = 0;
            for i = t_1:0.02:t_2
                count_2 = count_2 + 1;
                X_2(count_2) = count_2 * 0.02 + t_1;
                Y_2(count_2) = integral(@(t)(beta - lambda * X) * t,t_1,i) + B_1;
                
                plot(app.AxesBurned,X_2,Y_2,'r');
                area(app.AxesBurned,X_2,Y_2,'FaceColor','b');
                hold(app.AxesBurned,"on");
                             
                pause(1/10);
            end            

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {421, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '派出消防员数量';
            app.fighter = uispinner(app.LeftPanel);
            app.fighter.Limits = [1 Inf];
            app.fighter.RoundFractionalValues = 'on';
            app.fighter.ValueDisplayFormat = '%.0f';
            app.fighter.Value = 4;
            app.xLabel = uilabel(app.LeftPanel);
            app.xLabel.Interpreter = 'latex';
            app.xLabel.Text = '$x$';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '每人平均灭火速度';
            app.speedFighter = uieditfield(app.LeftPanel, 'numeric');
            app.speedFighter.Limits = [0 Inf];
            app.speedFighter.ValueDisplayFormat = '%.2f';
            app.speedFighter.Value = 10;
            app.lambdaLabel = uilabel(app.LeftPanel);
            app.lambdaLabel.Interpreter = 'latex';
            app.lambdaLabel.Text = '$\lambda$';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '每人单位时间支出';
            app.costTime = uieditfield(app.LeftPanel, 'numeric');
            app.costTime.Limits = [0 Inf];
            app.costTime.ValueDisplayFormat = '%.2f';
            app.costTime.Value = 42.5;
            app.c_2Label = uilabel(app.LeftPanel);
            app.c_2Label.Interpreter = 'latex';
            app.c_2Label.Text = '$c_2$';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '火势蔓延速度';
            app.speedFire = uieditfield(app.LeftPanel, 'numeric');
            app.speedFire.Limits = [0 Inf];
            app.speedFire.ValueDisplayFormat = '%.2f';
            app.speedFire.Value = 30;
            app.betaLabel = uilabel(app.LeftPanel);
            app.betaLabel.Interpreter = 'latex';
            app.betaLabel.Text = '$\beta$';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '开始救火时刻';
            app.timeBegin = uieditfield(app.LeftPanel, 'numeric');
            app.timeBegin.Limits = [0 Inf];
            app.timeBegin.ValueDisplayFormat = '%.1f';
            app.timeBegin.Value = 1.5;
            app.t_1Label = uilabel(app.LeftPanel);
            app.t_1Label.Interpreter = 'latex';
            app.t_1Label.Text = '$t_1$';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '单位烧毁面积损失';
            app.costFire = uieditfield(app.LeftPanel, 'numeric');
            app.costFire.Limits = [0 Inf];
            app.costFire.ValueDisplayFormat = '%.2f';
            app.costFire.Value = 825.6;
            app.c_1Label = uilabel(app.LeftPanel);
            app.c_1Label.Interpreter = 'latex';
            app.c_1Label.Text = '$c_1$';
            app.c_3Label = uilabel(app.LeftPanel);
            app.c_3Label.Interpreter = 'latex';
            app.c_3Label.Text = '$c_3$';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '每人一次性支出';
            app.costFighter = uieditfield(app.LeftPanel, 'numeric');
            app.costFighter.Limits = [0 Inf];
            app.costFighter.ValueDisplayFormat = '%.2f';
            app.costFighter.Value = 100;
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '森林救火仿真系统';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '💰';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '🚒';
            app.startButton = uibutton(app.LeftPanel, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = '开始仿真';
            app.Label_17 = uilabel(app.LeftPanel);
            app.Label_17.Text = '人';
            app.Label_20 = uilabel(app.LeftPanel);
            app.Label_20.Text = '小时后';
            app.Label_21 = uilabel(app.LeftPanel);
            app.Label_21.Text = '元/亩';
            app.Label_22 = uilabel(app.LeftPanel);
            app.Label_22.Text = '元/小时';
            app.Label_23 = uilabel(app.LeftPanel);
            app.Label_23.Text = '元/人';
            app.Label_24 = uilabel(app.LeftPanel);
            app.Label_24.Text = '亩/小时';
            app.Label_25 = uilabel(app.LeftPanel);
            app.Label_25.Text = '亩/小时';
            app.Label_26 = uilabel(app.LeftPanel);
            app.Label_26.Interpreter = 'latex';
            app.Label_26.Text = '$^2$';
            app.RightPanel = uipanel(app.GridLayout);
            app.AxesTotalCost = uiaxes(app.RightPanel);
            title(app.AxesTotalCost, '经济损失')
            xlabel(app.AxesTotalCost, '时间')
            ylabel(app.AxesTotalCost, '元')
            zlabel(app.AxesTotalCost, 'Z')
            app.AxesBurned = uiaxes(app.RightPanel);
            title(app.AxesBurned, '燃烧区域')
            xlabel(app.AxesBurned, '时间')
            ylabel(app.AxesBurned, '亩')
            zlabel(app.AxesBurned, 'Z')
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = '灭火时间：';
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = '烧毁面积：';
            app.Label_13 = uilabel(app.RightPanel);
            app.Label_13.Text = '烧毁损失';
            app.Label_14 = uilabel(app.RightPanel);
            app.Label_14.Text = '救援费用';
            app.Label_15 = uilabel(app.RightPanel);
            app.Label_15.Text = '总经济损失:';
            app.outputTime = uilabel(app.RightPanel);
            app.outputTime.Text = '';
            app.outputArea = uilabel(app.RightPanel);
            app.outputArea.Text = '';
            app.outputCostTotal = uilabel(app.RightPanel);
            app.outputCostTotal.Text = ' ';
            app.outputCostFighter = uilabel(app.RightPanel);
            app.outputCostFighter.Text = '';
            app.outputCostForest = uilabel(app.RightPanel);
            app.outputCostForest.Text = '';
            app.Label_27 = uilabel(app.RightPanel);
            app.Label_27.Text = '仿真预测结果:';
            app.Label_28 = uilabel(app.RightPanel);
            app.Label_28.Text = '森林模拟';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end